classdef app_ROI_2p_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FullimageanalysisButton     matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
        IntesnitymeanwithmaskLabel  matlab.ui.control.Label
        lntensitymeanLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        ApplytheregionsofinteresttoallimagesButton  matlab.ui.control.Button
        ROIanalysisButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
%             xlabel(app.UIAxes_2, []);
%             ylabel(app.UIAxes_2, []);
%             app.UIAxes_2.XAxis.TickLabels = {};
%             app.UIAxes_2.YAxis.TickLabels = {};
        end

        % Button pushed function: ROIanalysisButton
        function ROIanalysisButtonPushed(app, event)
            global Flux0;
            Flux0 = evalin('base','AFlux0');
            

            Image = mat2gray(Flux0);
            
%             uiwait(msgbox('Please selet the Ipsilateral Hemisphere'));
            Ipsilateral(Image);
            
%             uiwait(msgbox('Please selet the Contralateral Hemisphere'));
%             Contralateral(Image);
            
            uiwait(msgbox('Now close thse ROI selection window'));
            
            function [] = Ipsilateral(I)
                image(I); axis off;
                ROI = roipoly(I); 
                myImage = findall(gcf,'type','image');
                set(myImage,'AlphaData',ROI);
                assignin('base', 'ROI_Ipsi', ROI)
                
                Flux0_ROI = Flux0 .* ROI;
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                I = imagesc(Flux0_ROI, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);            
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                    
            end
            
%             
%             function [] = Contralateral(I)
%                 image(I); axis off;
%                 ROI = roipoly(I); 
%                 myImage = findall(gcf,'type','image');
%                 set(myImage,'AlphaData',ROI);
%                 assignin('base', 'ROI_contra', ROI)
%                 
%                 Flux0_ROI = Flux0 .* ROI;
%                 xlabel(app.UIAxes_2, []);
%                 ylabel(app.UIAxes_2, []);
%                 app.UIAxes_2.XAxis.TickLabels = {};
%                 app.UIAxes_2.YAxis.TickLabels = {};
%                 I = imagesc(Flux0_ROI, 'Parent', app.UIAxes_2, ...
%                     'XData', [1 app.UIAxes_2.Position(3)], ...
%                     'YData', [1 app.UIAxes_2.Position(4)]);            
%                 app.UIAxes_2.XLim = [0 I.XData(2)];
%                 app.UIAxes_2.YLim = [0 I.YData(2)];               
%             end
        
        end

        % Button pushed function: 
        % ApplytheregionsofinteresttoallimagesButton
        function ApplytheregionsofinteresttoallimagesButtonPushed(app, event)
         Flux0 = evalin('base','AFlux0');
         Flux1_N = evalin('base','Flux1_N');

         Flux0_cut = evalin('base','Flux0_cut');
         Flux1_cut = evalin('base','Flux1_cut');

         ROI_Ipsi = evalin('base','ROI_Ipsi');
%          ROI_contra = evalin('base','ROI_contra');
        
        Flux0_ROI_Ipsilateral = Flux0 .* ROI_Ipsi;
        assignin('base', 'Flux0_ROI_Ipsilateral', Flux0_ROI_Ipsilateral);
%         Flux0_ROI_Contralateral = Flux0 .* ROI_contra;
%         assignin('base', 'Flux0_ROI_Contralateral', Flux0_ROI_Contralateral);
        
        Flux1_ROI_Ipsilateral = Flux1_N .* ROI_Ipsi;
        assignin('base', 'Flux1_ROI_Ipsilateral', Flux1_ROI_Ipsilateral);
%         Flux1_ROI_Contralateral = Flux1_N .* ROI_contra;
%         assignin('base', 'Flux1_ROI_Contralateral', Flux1_ROI_Contralateral);

        Flux0_ROI_Ipsilateral_Mask = Flux0_cut .* ROI_Ipsi;
        assignin('base', 'Flux0_ROI_Ipsilateral_Mask', Flux0_ROI_Ipsilateral_Mask);
%         Flux0_ROI_Contralateral_Mask = Flux0_cut .* ROI_contra;
%         assignin('base', 'Flux0_ROI_Contralateral_Mask', Flux0_ROI_Contralateral_Mask);
        
        Flux1_ROI_Ipsilateral_Mask = Flux1_cut .* ROI_Ipsi;
        assignin('base', 'Flux1_ROI_Ipsilateral_Mask', Flux1_ROI_Ipsilateral_Mask);
%         Flux1_ROI_Contralateral_Mask = Flux1_cut .* ROI_contra;
%         assignin('base', 'Flux1_ROI_Contralateral_Mask', Flux1_ROI_Contralateral_Mask);

        Mean_Flux0_ROI_Ipsilateral = mean(mean(Flux0_ROI_Ipsilateral));
        Mean_Flux1_ROI_Ipsilateral = mean(mean(Flux1_ROI_Ipsilateral));
%         Mean_Flux0_ROI_Contralateral = mean(mean(Flux0_ROI_Contralateral));
%         Mean_Flux1_ROI_Contralateral = mean(mean(Flux1_ROI_Contralateral));
        Mean_Flux0_ROI_Ipsilateral_Mask = mean(mean(Flux0_ROI_Ipsilateral_Mask));
        Mean_Flux1_ROI_Ipsilateral_Mask = mean(mean(Flux1_ROI_Ipsilateral_Mask));
%         Mean_Flux0_ROI_Contralateral_Mask = mean(mean(Flux0_ROI_Contralateral_Mask));
%         Mean_Flux1_ROI_Contralateral_Mask = mean(mean(Flux1_ROI_Contralateral_Mask));
        
        Means = [Mean_Flux0_ROI_Ipsilateral, Mean_Flux1_ROI_Ipsilateral;
%                Mean_Flux0_ROI_Contralateral, Mean_Flux1_ROI_Contralateral;
               Mean_Flux0_ROI_Ipsilateral_Mask, Mean_Flux1_ROI_Ipsilateral_Mask;
%                Mean_Flux0_ROI_Contralateral_Mask, Mean_Flux1_ROI_Contralateral_Mask
               ];
           
      Variables = evalin('base', 'who');      
AFlux2 = ismember('AFlux2',Variables);
AFlux3 = ismember('AFlux3',Variables);
if AFlux2 > 0
    Flux2_N = evalin('base','Flux2_N');
    Flux2_cut = evalin('base','Flux2_cut');
    
    Flux2_ROI_Ipsilateral = Flux2_N .* ROI_Ipsi;
    assignin('base', 'Flux2_ROI_Ipsilateral', Flux2_ROI_Ipsilateral);
%     Flux2_ROI_Contralateral = Flux2_N .* ROI_contra;
%     assignin('base', 'Flux2_ROI_Contralateral', Flux2_ROI_Contralateral);
%     
    Flux2_ROI_Ipsilateral_Mask = Flux2_cut .* ROI_Ipsi;
    assignin('base', 'Flux2_ROI_Ipsilateral_Mask', Flux2_ROI_Ipsilateral_Mask);
%     Flux2_ROI_Contralateral_Mask = Flux2_cut .* ROI_contra;
%     assignin('base', 'Flux2_ROI_Contralateral_Mask', Flux2_ROI_Contralateral_Mask);
    
    Mean_Flux2_ROI_Ipsilateral = mean(mean(Flux2_ROI_Ipsilateral));
%     Mean_Flux2_ROI_Contralateral = mean(mean(Flux2_ROI_Contralateral));
    Mean_Flux2_ROI_Ipsilateral_Mask = mean(mean(Flux2_ROI_Ipsilateral_Mask));
%     Mean_Flux2_ROI_Contralateral_Mask = mean(mean(Flux2_ROI_Contralateral_Mask));
    
    Means = [Mean_Flux0_ROI_Ipsilateral, Mean_Flux1_ROI_Ipsilateral, Mean_Flux2_ROI_Ipsilateral;
%                Mean_Flux0_ROI_Contralateral, Mean_Flux1_ROI_Contralateral, Mean_Flux2_ROI_Contralateral;
               Mean_Flux0_ROI_Ipsilateral_Mask, Mean_Flux1_ROI_Ipsilateral_Mask, Mean_Flux2_ROI_Ipsilateral_Mask;
%                Mean_Flux0_ROI_Contralateral_Mask, Mean_Flux1_ROI_Contralateral_Mask, Mean_Flux2_ROI_Contralateral_Mask
                ];
end

if AFlux3 > 0
    Flux3_N = evalin('base','Flux3_N');
    Flux3_cut = evalin('base','Flux3_cut');
    
    Flux3_ROI_Ipsilateral = Flux3_N .* ROI_Ipsi;
    assignin('base', 'Flux3_ROI_Ipsilateral', Flux3_ROI_Ipsilateral);
%     Flux3_ROI_Contralateral = Flux3_N .* ROI_contra;
%     assignin('base', 'Flux3_ROI_Contralateral', Flux3_ROI_Contralateral);
    
    Flux3_ROI_Ipsilateral_Mask = Flux3_cut .* ROI_Ipsi;
    assignin('base', 'Flux3_ROI_Ipsilateral_Mask', Flux3_ROI_Ipsilateral_Mask);
%     Flux3_ROI_Contralateral_Mask = Flux3_cut .* ROI_contra;
%     assignin('base', 'Flux3_ROI_Contralateral_Mask', Flux3_ROI_Contralateral_Mask);
    
    Mean_Flux3_ROI_Ipsilateral = mean(mean(Flux3_ROI_Ipsilateral));
%     Mean_Flux3_ROI_Contralateral = mean(mean(Flux3_ROI_Contralateral));
    Mean_Flux3_ROI_Ipsilateral_Mask = mean(mean(Flux3_ROI_Ipsilateral_Mask));
%     Mean_Flux3_ROI_Contralateral_Mask = mean(mean(Flux3_ROI_Contralateral_Mask));
    
    Means = [Mean_Flux0_ROI_Ipsilateral, Mean_Flux1_ROI_Ipsilateral, Mean_Flux2_ROI_Ipsilateral, Mean_Flux3_ROI_Ipsilateral;
%                Mean_Flux0_ROI_Contralateral, Mean_Flux1_ROI_Contralateral, Mean_Flux2_ROI_Contralateral, Mean_Flux3_ROI_Contralateral;
               Mean_Flux0_ROI_Ipsilateral_Mask, Mean_Flux1_ROI_Ipsilateral_Mask, Mean_Flux2_ROI_Ipsilateral_Mask, Mean_Flux3_ROI_Ipsilateral_Mask;
%                Mean_Flux0_ROI_Contralateral_Mask, Mean_Flux1_ROI_Contralateral_Mask, Mean_Flux2_ROI_Contralateral_Mask, Mean_Flux3_ROI_Contralateral_Mask
                ];
end
                
           
       assignin('base', 'Means', Means);  
          

       app.UITable.Data = Means; 
        
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app_mask_2p;
            app.delete;
        end

        % Button pushed function: FullimageanalysisButton
        function FullimageanalysisButtonPushed(app, event)
         
         Flux0_cut = evalin('base','Flux0_cut');
         Flux1_cut = evalin('base','Flux1_cut');


        image1_intensity = mean(mean(image1));
        image2_intensity = mean(mean(image2));
        image3_intensity = mean(mean(image3));
        image4_intensity = mean(mean(image4));


            assignin('base', 'Means', Means);  
          

       app.UITable.Data = Means; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' Region of Interest')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ROIanalysisButton = uibutton(app.UIFigure, 'push');
            app.ROIanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ROIanalysisButtonPushed, true);
            app.ROIanalysisButton.Text = 'ROI analysis';
            app.ApplytheregionsofinteresttoallimagesButton = uibutton(app.UIFigure, 'push');
            app.ApplytheregionsofinteresttoallimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytheregionsofinteresttoallimagesButtonPushed, true);
            app.ApplytheregionsofinteresttoallimagesButton.Text = {'Apply the regions of '; 'interest to all images'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'First Image'; 'Second Image'; 'Third Image'; 'Fourth Image'};
            app.UITable.RowName = {};
            app.lntensitymeanLabel = uilabel(app.UIFigure);
            app.lntensitymeanLabel.Text = 'lntensity mean';
            app.IntesnitymeanwithmaskLabel = uilabel(app.UIFigure);
            app.IntesnitymeanwithmaskLabel.Text = 'Intesnity mean with mask';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.FullimageanalysisButton = uibutton(app.UIFigure, 'push');
            app.FullimageanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FullimageanalysisButtonPushed, true);
            app.FullimageanalysisButton.Text = 'Full image analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ROI_2p_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end