classdef app_loading_2p < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NextButton                matlab.ui.control.Button
        LoadthefourthImageButton  matlab.ui.control.Button
        LoadthethirdImageButton   matlab.ui.control.Button
        LoadthesecondImageButton  matlab.ui.control.Button
        LoadthefirstImageButton   matlab.ui.control.Button
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


%      uiwait(msgbox('The app will load the saved workspace variables, respecting the numbered order.'));
%             IJM.getDataset

            
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            
            xlabel(app.UIAxes_4, []);
            ylabel(app.UIAxes_4, []);
            app.UIAxes_4.XAxis.TickLabels = {};
            app.UIAxes_4.YAxis.TickLabels = {};

            
        end

        % Button pushed function: LoadthefirstImageButton
        function LoadthefirstImageButtonPushed(app, event)

            AFlux0 = evalin('base','image1');
            assignin('base', 'AFlux0', AFlux0);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(AFlux0, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];           
                       
        end

        % Button pushed function: LoadthesecondImageButton
        function LoadthesecondImageButtonPushed(app, event)
            
            AFlux1 = evalin('base','image2');
            assignin('base', 'AFlux1', AFlux1);            
           
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            I = imagesc(AFlux1, 'Parent', app.UIAxes_2, ...
                'XData', [1 app.UIAxes_2.Position(3)], ...
                'YData', [1 app.UIAxes_2.Position(4)]);            
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];               
        end

        % Button pushed function: LoadthethirdImageButton
        function LoadthethirdImageButtonPushed(app, event)

            AFlux2 = evalin('base','image3');
            assignin('base', 'AFlux2', AFlux2);            
            
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            I = imagesc(AFlux2, 'Parent', app.UIAxes_3, ...
                'XData', [1 app.UIAxes_3.Position(3)], ...
                'YData', [1 app.UIAxes_3.Position(4)]);            
            app.UIAxes_3.XLim = [0 I.XData(2)];
            app.UIAxes_3.YLim = [0 I.YData(2)];            
        end

        % Button pushed function: LoadthefourthImageButton
        function LoadthefourthImageButtonPushed(app, event)

            AFlux3 = evalin('base','image4');
            assignin('base', 'AFlux3', AFlux3);            
            
            xlabel(app.UIAxes_4, []);
            ylabel(app.UIAxes_4, []);
            app.UIAxes_4.XAxis.TickLabels = {};
            app.UIAxes_4.YAxis.TickLabels = {};
            I = imagesc(AFlux3, 'Parent', app.UIAxes_4, ...
                'XData', [1 app.UIAxes_4.Position(3)], ...
                'YData', [1 app.UIAxes_4.Position(4)]);            
            app.UIAxes_4.XLim = [0 I.XData(2)];
            app.UIAxes_4.YLim = [0 I.YData(2)];  
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            Variables = evalin('base', 'who');      
            AFlux2 = ismember('AFlux1',Variables);
            
            if AFlux2 == 0
%                 uiwait(msgbox('In order to continue import at least one more image'));
            else 
                app_overlap_2p;
                app.delete;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'First Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Second Image')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Third Image')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Fourth Image')
            app.LoadthefirstImageButton = uibutton(app.UIFigure, 'push');
            app.LoadthefirstImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthefirstImageButtonPushed, true);
            app.LoadthefirstImageButton.Text = 'Load the first Image';
            app.LoadthesecondImageButton = uibutton(app.UIFigure, 'push');
            app.LoadthesecondImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthesecondImageButtonPushed, true);
            app.LoadthesecondImageButton.Text = 'Load the second Image';
            app.LoadthethirdImageButton = uibutton(app.UIFigure, 'push');
            app.LoadthethirdImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthethirdImageButtonPushed, true);
            app.LoadthethirdImageButton.Text = 'Load the third Image';
            app.LoadthefourthImageButton = uibutton(app.UIFigure, 'push');
            app.LoadthefourthImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthefourthImageButtonPushed, true);
            app.LoadthefourthImageButton.Text = 'Load the fourth Image';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_loading_2p

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end