classdef app_mask_2p < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NextButton            matlab.ui.control.Button
        BackButton            matlab.ui.control.Button
        ApplymasktoallButton  matlab.ui.control.Button
        Highslider            matlab.ui.control.Slider
        UpperthresholdLabel   matlab.ui.control.Label
        Lowslider             matlab.ui.control.Slider
        LowerthresholdLabel   matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             uiwait(msgbox('Now we will eliminate the low flux value pixels and high flux value pixels'));
%             uiwait(msgbox('Please select the desired upper and Lower limits'));            
            Flux0 = evalin('base','AFlux0');
            Flux0_max = max(max(Flux0));
            app.Lowslider.Limits = [0 , Flux0_max];
            app.Highslider.Limits = [0 , Flux0_max];
            assignin('base', 'Fluxmask', Flux0);
            assignin('base', 'Fluxmask_high', Flux0);
            assignin('base', 'Fluxmask_low', Flux0);
            
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(Flux0, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];

            clear movingReg1 movingReg2 movingReg3
        end

        % Button pushed function: ApplymasktoallButton
        function ApplymasktoallButtonPushed(app, event)
            Flux0 = evalin('base','AFlux0');
            Flux1_N = evalin('base','Flux1_N');            
            Fluxmask_high = evalin('base','Fluxmask_high');
            Fluxmask_low = evalin('base','Fluxmask_low');
            Fluxmask = evalin('base','Fluxmask');
            
            BW_high = im2bw(Fluxmask_high,1);
            BW_low = im2bw(Fluxmask_low,1);
            Fluxmask = Fluxmask .* BW_high;
            Fluxmask = Fluxmask .* BW_low;

            BW = im2bw(Fluxmask,1);            
            Flux0_cut = Flux0 .* BW;
            Flux1_cut = Flux1_N .* BW;          
         
            assignin('base', 'Flux0_cut', Flux0_cut);
            assignin('base', 'Flux1_cut', Flux1_cut);          
 
            Variables = evalin('base', 'who');      
            AFlux2 = ismember('AFlux2',Variables);
            AFlux3 = ismember('AFlux3',Variables);
            if AFlux2 > 0
                Flux2_N = evalin('base','Flux2_N');
                Flux2_cut = Flux2_N .* BW;
                assignin('base', 'Flux2_cut', Flux2_cut);
            end
            
            if AFlux3 > 0
                Flux3_N = evalin('base','Flux3_N');
                Flux3_cut = Flux3_N .* BW;
                assignin('base', 'Flux3_cut', Flux3_cut);
            end
            
        end

        % Value changed function: Highslider
        function HighsliderValueChanged(app, event)
            value = app.Highslider.Value;
            value_text = num2str(value);
%             app.HighValueLabel.Text = value_text;
            
            Flux0 = evalin('base','AFlux0');            
            Fluxmask_low = evalin('base','Fluxmask_low');
            
            BW = im2bw(Fluxmask_low,1);
            
            Flux0(Flux0>value)=0;            
            Fluxmask_high = Flux0;
            
            Fluxmask_high_low = Fluxmask_high .* BW;

            assignin('base', 'Fluxmask_high', Fluxmask_high);
            assignin('base', 'Fluxmask_high_low', Fluxmask_high_low);


            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(Fluxmask_high_low, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end

        % Value changed function: Lowslider
        function LowsliderValueChanged(app, event)
            value = app.Lowslider.Value;
            value_text = num2str(value);
%             app.LowValueLabel.Text = value_text;
            
            Fluxmask_high = evalin('base','Fluxmask_high');
            Flux0 = evalin('base','AFlux0');
            
            BW = im2bw(Fluxmask_high,1);
            
            Flux0(Flux0<value)=0;
            Fluxmask_low = Flux0;
            Fluxmask_low_high = Fluxmask_low .* BW;                        

            assignin('base', 'Fluxmask_low', Fluxmask_low);
            assignin('base', 'Fluxmask_low_high', Fluxmask_low_high);


            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(Fluxmask_low_high, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end

        % Callback function
        function Button2Pushed(app, event)
            Fluxmask_high = evalin('base','Fluxmask_high');
            Fluxmask_low = evalin('base','Fluxmask_low');
            Fluxmask = evalin('base','Fluxmask');
            BW_high = im2bw(Fluxmask_high,1);
            BW_low = im2bw(Fluxmask_low,1);
            Fluxmask = Fluxmask .* BW_high;
            Fluxmask = Fluxmask .* BW_low;
            assignin('base', 'Fluxmask', Fluxmask);
            
        end

        % Value changing function: Highslider
        function HighsliderValueChanging(app, event)

        end

        % Value changing function: Lowslider
        function LowsliderValueChanging(app, event)

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app_overlap;
            app.delete;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            
            app_ROI_2p;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LowerthresholdLabel = uilabel(app.UIFigure);
            app.LowerthresholdLabel.Text = 'Lower threshold';
            app.Lowslider = uislider(app.UIFigure);
            app.Lowslider.ValueChangedFcn = createCallbackFcn(app, @LowsliderValueChanged, true);
            app.Lowslider.ValueChangingFcn = createCallbackFcn(app, @LowsliderValueChanging, true);
            app.UpperthresholdLabel = uilabel(app.UIFigure);
            app.UpperthresholdLabel.Text = 'Upper threshold';
            app.Highslider = uislider(app.UIFigure);
            app.Highslider.ValueChangedFcn = createCallbackFcn(app, @HighsliderValueChanged, true);
            app.Highslider.ValueChangingFcn = createCallbackFcn(app, @HighsliderValueChanging, true);
            app.ApplymasktoallButton = uibutton(app.UIFigure, 'push');
            app.ApplymasktoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplymasktoallButtonPushed, true);
            app.ApplymasktoallButton.Text = 'Apply mask to all';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_mask_2p

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end