classdef app_overlap_2p < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        NextButton  matlab.ui.control.Button
        BackButton  matlab.ui.control.Button
        Overlap3    matlab.ui.control.Button
        Overlap2    matlab.ui.control.Button
        Overlap1    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Variables = evalin('base', 'who');
            AFlux2 = ismember('AFlux2',Variables);
            AFlux3 = ismember('AFlux3',Variables);
            if AFlux2 > 0
                set(app.Overlap2, 'Enable', 'on')
            end
            if AFlux3 > 0
                set(app.Overlap3, 'Enable', 'on')
            end
           
            Flux0 = evalin('base','AFlux0');
            Flux0_max = max(max(Flux0));
            Flux0_normalised = Flux0 ./ Flux0_max;
            assignin('base', 'Flux0_normalised', Flux0_normalised );

%             uiwait(msgbox('Select which images to overlap and register. Export registration to workspace'));
%             uiwait(msgbox('Use a different name for each registration i.e movingReg1'));
%                 
            
        end

        % Button pushed function: Overlap1
        function Overlap1Pushed(app, event)
            Flux0 = evalin('base','AFlux0');
            Flux0_max = max(max(Flux0));
            Flux0_normalised = Flux0 ./ Flux0_max;
            assignin('base', 'Flux0_normalised', Flux0_normalised );

            Flux1 = evalin('base','AFlux1');
            Flux1_max = max(max(Flux1));
            Flux1_normalised = Flux1 ./ Flux1_max;
            assignin('base', 'Flux1_normalised', Flux1_normalised );
            assignin('base', 'Flux1_max', Flux1_max);
            
            MOVING = Flux1_normalised;
            FIXED = Flux0_normalised;
%             registrationEstimator(MOVING, FIXED);
            [optimizer, metric] = imregconfig('monomodal');
            movingReg1 = imregister(MOVING, FIXED, 'affine', optimizer, metric);
            assignin('base', 'movingReg1', movingReg1);
            imshowpair(FIXED, movingReg1,'Scaling','joint')
        end

        % Button pushed function: Overlap2
        function Overlap2ButtonPushed(app, event)
            Flux2 = evalin('base','AFlux2');
            Flux0_normalised = evalin('base','Flux0_normalised');
            Flux2_max = max(max(Flux2));
            Flux2_normalised = Flux2 ./ Flux2_max;
            assignin('base', 'Flux2_max', Flux2_max);
            assignin('base', 'Flux2_normalised', Flux2_normalised );
            
            MOVING = Flux2_normalised;
            FIXED = Flux0_normalised;
%             registrationEstimator(MOVING, FIXED);
            [optimizer, metric] = imregconfig('monomodal');
            movingReg2 = imregister(MOVING, FIXED, 'affine', optimizer, metric);
            assignin('base', 'movingReg2', movingReg2);            
            imshowpair(FIXED, movingReg2,'Scaling','joint')

        end

        % Button pushed function: Overlap3
        function Overlap3ButtonPushed(app, event)
            Flux3 = evalin('base','AFlux3');
            Flux0_normalised = evalin('base','Flux0_normalised');
            Flux3_max = max(max(Flux3));
            Flux3_normalised = Flux3 ./ Flux3_max;
            assignin('base', 'Flux3_max', Flux3_max);
            assignin('base', 'Flux3_normalised', Flux3_normalised );
            
            MOVING = Flux3_normalised;
            FIXED = Flux0_normalised;
%             registrationEstimator(MOVING, FIXED);
            [optimizer, metric] = imregconfig('monomodal');
            movingReg3 = imregister(MOVING, FIXED, 'affine', optimizer, metric);
            assignin('base', 'movingReg3', movingReg3);
            imshowpair(FIXED, movingReg3,'Scaling','joint')
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app_loading;
            app.delete;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            movingReg1.RegisteredImage = evalin('base','movingReg1');

            Flux1_max = evalin('base','Flux1_max');

            Flux1_N = movingReg1.RegisteredImage .* Flux1_max;

            assignin('base', 'Flux1_N', Flux1_N );


            Variables = evalin('base', 'who');      
            AFlux2 = ismember('AFlux2',Variables);
            AFlux3 = ismember('AFlux3',Variables);
            if AFlux2 > 0
                movingReg2.RegisteredImage = evalin('base','movingReg2');
                Flux2_max = evalin('base','Flux2_max');
                Flux2_N = movingReg2.RegisteredImage .* Flux2_max;
                assignin('base', 'Flux2_N', Flux2_N );
            end
            if AFlux3 > 0
                movingReg3.RegisteredImage = evalin('base','movingReg3');
                Flux3_max = evalin('base','Flux3_max');
                Flux3_N = movingReg3.RegisteredImage .* Flux3_max;
                assignin('base', 'Flux3_N', Flux3_N );
            end
            clear movingReg1 movingReg2 movingReg3
            app_mask_2p;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Overlap1 = uibutton(app.UIFigure, 'push');
            app.Overlap1.ButtonPushedFcn = createCallbackFcn(app, @Overlap1Pushed, true);
            app.Overlap1.Text = 'Overlap the first and second Images';
            app.Overlap2 = uibutton(app.UIFigure, 'push');
            app.Overlap2.ButtonPushedFcn = createCallbackFcn(app, @Overlap2ButtonPushed, true);
            app.Overlap2.Enable = 'off';
            app.Overlap2.Text = 'Overlap the first and third images';
            app.Overlap3 = uibutton(app.UIFigure, 'push');
            app.Overlap3.ButtonPushedFcn = createCallbackFcn(app, @Overlap3ButtonPushed, true);
            app.Overlap3.Enable = 'off';
            app.Overlap3.Text = 'Overlap the first and fourth images';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_overlap_2p

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end